classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ComplexNetworkAnalysisUIFigure  matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        KzlekedsihlzatokvizsglataLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        AutomatikuselemzsTab            matlab.ui.container.Tab
        DebrecenCheckBox                matlab.ui.control.CheckBox
        GyrCheckBox                     matlab.ui.control.CheckBox
        MiskolcCheckBox                 matlab.ui.control.CheckBox
        PcsCheckBox                     matlab.ui.control.CheckBox
        SzegedCheckBox                  matlab.ui.control.CheckBox
        ElemzsiidEditFieldLabel         matlab.ui.control.Label
        ElemzsiidEditField              matlab.ui.control.NumericEditField
        ElemezButton_2                  matlab.ui.control.Button
        ManuliselemzsTab                matlab.ui.container.Tab
        VrosLabel                       matlab.ui.control.Label
        VarosKivalaszt                  matlab.ui.control.DropDown
        ElemezButton                    matlab.ui.control.Button
        lekszmaEditFieldLabel           matlab.ui.control.Label
        lekszmaEditField                matlab.ui.control.NumericEditField
        CscsokszmaEditFieldLabel        matlab.ui.control.Label
        CscsokszmaEditField             matlab.ui.control.NumericEditField
        tlagosfokszmLabel               matlab.ui.control.Label
        tlagosfokszmEditField           matlab.ui.control.NumericEditField
        LegrvidebbtTab                  matlab.ui.container.Tab
        VarosKivalaszt_2Label           matlab.ui.control.Label
        VarosKivalaszt_legrovidebb      matlab.ui.control.DropDown
        KeresButton                     matlab.ui.control.Button
        LegrovidebbUtErdemenyField      matlab.ui.control.EditField
        MaximlisfolyamTab               matlab.ui.container.Tab
        VarosKivalaszt_legrovidebb_2Label  matlab.ui.control.Label
        VarosKivalaszt_maximalis        matlab.ui.control.DropDown
        KivlasztButton                  matlab.ui.control.Button
        MaximalisFolyamErdemenyField    matlab.ui.control.EditField
        SajthlzatelemzsTab              matlab.ui.container.Tab
        BeolvasButton                   matlab.ui.control.Button
        lekszmaEditField_2Label         matlab.ui.control.Label
        lekszmaEditField_2              matlab.ui.control.NumericEditField
        CscsokszmaEditField_2Label      matlab.ui.control.Label
        CscsokszmaEditField_2           matlab.ui.control.NumericEditField
        tlagosfokszmEditField_2Label    matlab.ui.control.Label
        tlagosfokszmEditField_2         matlab.ui.control.NumericEditField
        segedletKep                     matlab.ui.control.UIAxes
        ExcelmintaLabel                 matlab.ui.control.Label
        SztancsikDnielSzegediTudomnyegyetem2017Label  matlab.ui.control.Label
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('Public_transport.png','Parent',app.UIAxes);
            imshow('segedlet.png','Parent',app.segedletKep);
            %app.UITable.Visible='off';
        end

        % Button pushed function: ElemezButton
        function ElemezButtonPushed(app, event)
            varos=app.VarosKivalaszt.Value;
            switch varos
                case 'Debrecen'
                    debrecen=complexNetworkAnalyserGUI('Debrecen_w1_edges.csv', 'Debrecen_w1_nodes.csv');
                    assignin('base', 'debrecen', debrecen);
                    app.lekszmaEditField.Value=debrecen.elekSzama;
                    app.CscsokszmaEditField.Value=debrecen.csucsokSzama;
                    app.tlagosfokszmEditField.Value=debrecen.atlagFok;
                    
                    %app.SlyozottCheckBox.Value
                    %app.DebrecenButton.Visible='off';
                case 'Győr'
                    gyor=complexNetworkAnalyserGUI('Gyor_w1_edges.csv', 'Gyor_w1_nodes.csv');
                    assignin('base', 'gyor', gyor);
                    app.lekszmaEditField.Value=gyor.elekSzama;
                    app.CscsokszmaEditField.Value=gyor.csucsokSzama;
                    app.tlagosfokszmEditField.Value=gyor.atlagFok;
                    
                case 'Miskolc'
                    miskolc=complexNetworkAnalyserGUI('Miskolc_w1_edges.csv', 'Miskolc_w1_nodes.csv');
                    assignin('base', 'miskolc', miskolc);
                    app.lekszmaEditField.Value=miskolc.elekSzama;
                    app.CscsokszmaEditField.Value=miskolc.csucsokSzama;
                    app.tlagosfokszmEditField.Value=miskolc.atlagFok;
                    
                case 'Pécs'
                    pecs=complexNetworkAnalyserGUI('Pecs_w1_edges.csv','Pecs_w1_nodes.csv');
                    assignin('base', 'pecs', pecs);
                    app.lekszmaEditField.Value=pecs.elekSzama;
                    app.CscsokszmaEditField.Value=pecs.csucsokSzama;
                    app.tlagosfokszmEditField.Value=pecs.atlagFok;
                    
                case 'Szeged'
                    szeged=complexNetworkAnalyserGUI('Szeged_w1_edges.csv', 'Szeged_w1_nodes.csv');
                    assignin('base', 'szeged', szeged);
                    app.lekszmaEditField.Value=szeged.elekSzama;
                    app.CscsokszmaEditField.Value=szeged.csucsokSzama;
                    app.tlagosfokszmEditField.Value=szeged.atlagFok;
                    
            end
        end

        % Button pushed function: ElemezButton_2
        function ElemezAutoButtonPushed(app, event)
            cityFileName={'Debrecen_w1_edges.csv','Gyor_w1_edges.csv','Miskolc_w1_edges.csv','Pecs_w1_edges.csv','Szeged_w1_edges.csv'};
            cities=zeros(1,5,'logical');
            cities(1)=app.DebrecenCheckBox.Value;
            cities(2)=app.GyrCheckBox.Value;
            cities(3)=app.MiskolcCheckBox.Value;
            cities(4)=app.PcsCheckBox.Value;
            cities(5)=app.SzegedCheckBox.Value;
            count=0;
            %Count - a pipalt varosok szama
            for i=1:5
                if(cities(i))
                    count=count+1;
                end
            end
            switch count
                case 0
                    helpdlg('Legalább egy város kiválasztása szükséges!','Város kiválasztás');
                    
                case 1
                    for i=1:5
                        if(cities(i))
                            currentVar = char(cityFileName(i));
                        end
                    end
                    [data,time]=autoNetworkAnalyser(currentVar);
                case 2
                    citycount=0;
                    for i=1:5
                        if(cities(i) && citycount == 0)
                            currentVar1 = char(cityFileName(i));
                            citycount=citycount+1;
                        elseif(cities(i) && citycount == 1)
                            currentVar2 = char(cityFileName(i));
                            citycount=citycount+1;
                        end
                    end
                    [data,time]=autoNetworkAnalyser(currentVar1,currentVar2);
                    
                case 3
                    citycount=0;
                    for i=1:5
                        if(cities(i))
                            switch citycount
                                case 0
                                    currentVar1 = char(cityFileName(i));
                                    citycount=citycount+1;
                                case 1
                                    currentVar2 = char(cityFileName(i));
                                    citycount=citycount+1;
                                case 2
                                    currentVar3 = char(cityFileName(i));
                                    citycount=citycount+1;
                            end
                        end
                    end
                    [data,time]=autoNetworkAnalyser(currentVar1,currentVar2,currentVar3);
                case 4
                    citycount=0;
                    for i=1:5
                        if(cities(i))
                            switch citycount
                                case 0
                                    currentVar1 = char(cityFileName(i));
                                    citycount=citycount+1;
                                case 1
                                    currentVar2 = char(cityFileName(i));
                                    citycount=citycount+1;
                                case 2
                                    currentVar3 = char(cityFileName(i));
                                    citycount=citycount+1;
                                case 3
                                    currentVar4 = char(cityFileName(i));
                                    citycount=citycount+1;
                            end
                        end
                    end
                    [data,time]=autoNetworkAnalyser(currentVar1,currentVar2,currentVar3,currentVar4);
                case 5
                    citycount=0;
                    for i=1:5
                        if(cities(i))
                            switch citycount
                                case 0
                                    currentVar1 = char(cityFileName(i));
                                    citycount=citycount+1;
                                case 1
                                    currentVar2 = char(cityFileName(i));
                                    citycount=citycount+1;
                                case 2
                                    currentVar3 = char(cityFileName(i));
                                    citycount=citycount+1;
                                case 3
                                    currentVar4 = char(cityFileName(i));
                                    citycount=citycount+1;
                                case 4
                                    currentVar5 = char(cityFileName(i));
                                    citycount=citycount+1;
                            end
                        end
                    end
                    [data,time]=autoNetworkAnalyser(currentVar1,currentVar2,currentVar3,currentVar4,currentVar5);
            end
            app.ElemzsiidEditField.Value=time;
            %Ablak magassaga 22,5 pixel soronkent (varosonkent) count+elso cimke sor
            f=figure('MenuBar','none','pos',[260 230 875 22.5*(count+1)]);
            uitable(f,'Data',data{:,:},'ColumnName',data.Properties.VariableNames,'RowName',data.Properties.RowNames,'Units', 'Normalized', 'Position',[0, 0, 1, 1],'FontSize',11);
        end

        % Button pushed function: KeresButton
        function KeresButtonPushed(app, event)
            %Legrövidebb út
            varos=app.VarosKivalaszt_legrovidebb.Value;
            switch varos
                case 'Debrecen'
                    [ ~, result ]=legrovidebbUtKeresoGUI('Debrecen_w1_edges.csv', 'Debrecen_w1_nodes.csv');
                case 'Győr'
                    [ ~, result ]=legrovidebbUtKeresoGUI('Gyor_w1_edges.csv', 'Gyor_w1_nodes.csv');
                case 'Miskolc'
                    [ ~, result ]=legrovidebbUtKeresoGUI('Miskolc_w1_edges.csv', 'Miskolc_w1_nodes.csv');
                case 'Pécs'
                    [ ~, result ]=legrovidebbUtKeresoGUI('Pecs_w1_edges.csv', 'Pecs_w1_nodes.csv');
                case 'Szeged'
                    [ ~, result ]=legrovidebbUtKeresoGUI('Szeged_w1_edges.csv', 'Szeged_w1_nodes.csv');
            end
            app.LegrovidebbUtErdemenyField.Value=result;
        end

        % Button pushed function: KivlasztButton
        function KivlasztButtonPushed(app, event)
            %Maximális folyam
            varos=app.VarosKivalaszt_maximalis.Value;
            switch varos
                case 'Debrecen'
                    [ ~, result ]=maximalisFolyamKeresoGUI('Debrecen_w1_edges.csv', 'Debrecen_w1_nodes.csv');
                case 'Győr'
                    [ ~, result ]=maximalisFolyamKeresoGUI('Gyor_w1_edges.csv', 'Gyor_w1_nodes.csv');
                case 'Miskolc'
                    [ ~, result ]=maximalisFolyamKeresoGUI('Miskolc_w1_edges.csv', 'Miskolc_w1_nodes.csv');
                case 'Pécs'
                    [ ~, result ]=maximalisFolyamKeresoGUI('Pecs_w1_edges.csv', 'Pecs_w1_nodes.csv');
                case 'Szeged'
                    [ ~, result ]=maximalisFolyamKeresoGUI('Szeged_w1_edges.csv', 'Szeged_w1_nodes.csv');
            end
            app.MaximalisFolyamErdemenyField.Value=result;
        end

        % Button pushed function: BeolvasButton
        function BeolvasButtonPushed(app, event)
            [FileName,PathName] = uigetfile({'*.csv';'*.xls';'*.xlsx'},'Kérem, válassza ki a hálózatot tartalmazó fájlt ! [csv,xls,xlsx]');
            if ( isa(FileName,'double') || isa(PathName,'double'))
                warndlg('Nem választott ki fájlt !', 'Warning' );
            else
                str=strcat(PathName,FileName);
                eredmeny  =  complexNetworkAnalyserGUI(str);
                assignin('base', 'eredmeny', eredmeny);
                app.lekszmaEditField_2.Value=eredmeny.elekSzama;
                app.CscsokszmaEditField_2.Value=eredmeny.csucsokSzama;
                app.tlagosfokszmEditField_2.Value=eredmeny.atlagFok;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ComplexNetworkAnalysisUIFigure = uifigure;
            app.ComplexNetworkAnalysisUIFigure.Name = 'Complex Network Analysis';
            setAutoResize(app, app.ComplexNetworkAnalysisUIFigure, false)
            app.UIAxes = uiaxes(app.ComplexNetworkAnalysisUIFigure);
            app.KzlekedsihlzatokvizsglataLabel = uilabel(app.ComplexNetworkAnalysisUIFigure);
            app.KzlekedsihlzatokvizsglataLabel.Text = 'Közlekedési hálózatok vizsgálata';
            app.TabGroup = uitabgroup(app.ComplexNetworkAnalysisUIFigure);
            app.AutomatikuselemzsTab = uitab(app.TabGroup);
            app.AutomatikuselemzsTab.Title = 'Automatikus elemzés';
            app.AutomatikuselemzsTab.Units = 'pixels';
            app.DebrecenCheckBox = uicheckbox(app.AutomatikuselemzsTab);
            app.DebrecenCheckBox.Text = 'Debrecen';
            app.GyrCheckBox = uicheckbox(app.AutomatikuselemzsTab);
            app.GyrCheckBox.Text = 'Győr';
            app.MiskolcCheckBox = uicheckbox(app.AutomatikuselemzsTab);
            app.MiskolcCheckBox.Text = 'Miskolc';
            app.PcsCheckBox = uicheckbox(app.AutomatikuselemzsTab);
            app.PcsCheckBox.Text = 'Pécs';
            app.SzegedCheckBox = uicheckbox(app.AutomatikuselemzsTab);
            app.SzegedCheckBox.Text = 'Szeged';
            app.ElemzsiidEditFieldLabel = uilabel(app.AutomatikuselemzsTab);
            app.ElemzsiidEditFieldLabel.Text = 'Elemzési idő:';
            app.ElemzsiidEditField = uieditfield(app.AutomatikuselemzsTab, 'numeric');
            app.ElemzsiidEditField.ValueDisplayFormat = '%5.2f';
            app.ElemzsiidEditField.Editable = 'off';
            app.ElemezButton_2 = uibutton(app.AutomatikuselemzsTab, 'push');
            app.ElemezButton_2.ButtonPushedFcn = createCallbackFcn(app, @ElemezAutoButtonPushed, true);
            app.ElemezButton_2.Text = 'Elemez';
            app.ManuliselemzsTab = uitab(app.TabGroup);
            app.ManuliselemzsTab.Title = 'Manuális elemzés';
            app.ManuliselemzsTab.Units = 'pixels';
            app.VrosLabel = uilabel(app.ManuliselemzsTab);
            app.VrosLabel.Text = 'Város:';
            app.VarosKivalaszt = uidropdown(app.ManuliselemzsTab);
            app.VarosKivalaszt.Items = {'Debrecen', 'Győr', 'Miskolc', 'Pécs', 'Szeged'};
            app.VarosKivalaszt.Value = 'Debrecen';
            app.ElemezButton = uibutton(app.ManuliselemzsTab, 'push');
            app.ElemezButton.ButtonPushedFcn = createCallbackFcn(app, @ElemezButtonPushed, true);
            app.ElemezButton.Text = 'Elemez';
            app.lekszmaEditFieldLabel = uilabel(app.ManuliselemzsTab);
            app.lekszmaEditFieldLabel.Text = 'Élek száma:';
            app.lekszmaEditField = uieditfield(app.ManuliselemzsTab, 'numeric');
            app.lekszmaEditField.Editable = 'off';
            app.CscsokszmaEditFieldLabel = uilabel(app.ManuliselemzsTab);
            app.CscsokszmaEditFieldLabel.Text = 'Csúcsok száma:';
            app.CscsokszmaEditField = uieditfield(app.ManuliselemzsTab, 'numeric');
            app.CscsokszmaEditField.Editable = 'off';
            app.tlagosfokszmLabel = uilabel(app.ManuliselemzsTab);
            app.tlagosfokszmLabel.Text = 'Átlagos fokszám:';
            app.tlagosfokszmEditField = uieditfield(app.ManuliselemzsTab, 'numeric');
            app.tlagosfokszmEditField.ValueDisplayFormat = '%5.2f';
            app.tlagosfokszmEditField.Editable = 'off';
            app.LegrvidebbtTab = uitab(app.TabGroup);
            app.LegrvidebbtTab.Title = 'Legrövidebb út';
            app.LegrvidebbtTab.Units = 'pixels';
            app.VarosKivalaszt_2Label = uilabel(app.LegrvidebbtTab);
            app.VarosKivalaszt_2Label.Text = 'Város:';
            app.VarosKivalaszt_legrovidebb = uidropdown(app.LegrvidebbtTab);
            app.VarosKivalaszt_legrovidebb.Items = {'Debrecen', 'Győr', 'Miskolc', 'Pécs', 'Szeged'};
            app.VarosKivalaszt_legrovidebb.Value = 'Debrecen';
            app.KeresButton = uibutton(app.LegrvidebbtTab, 'push');
            app.KeresButton.ButtonPushedFcn = createCallbackFcn(app, @KeresButtonPushed, true);
            app.KeresButton.Text = 'Keres';
            app.LegrovidebbUtErdemenyField = uieditfield(app.LegrvidebbtTab, 'text');
            app.LegrovidebbUtErdemenyField.Editable = 'off';
            app.MaximlisfolyamTab = uitab(app.TabGroup);
            app.MaximlisfolyamTab.Title = 'Maximális folyam';
            app.MaximlisfolyamTab.Units = 'pixels';
            app.VarosKivalaszt_legrovidebb_2Label = uilabel(app.MaximlisfolyamTab);
            app.VarosKivalaszt_legrovidebb_2Label.Text = 'Város:';
            app.VarosKivalaszt_maximalis = uidropdown(app.MaximlisfolyamTab);
            app.VarosKivalaszt_maximalis.Items = {'Debrecen', 'Győr', 'Miskolc', 'Pécs', 'Szeged'};
            app.VarosKivalaszt_maximalis.Value = 'Debrecen';
            app.KivlasztButton = uibutton(app.MaximlisfolyamTab, 'push');
            app.KivlasztButton.ButtonPushedFcn = createCallbackFcn(app, @KivlasztButtonPushed, true);
            app.KivlasztButton.Text = 'Kiválaszt';
            app.MaximalisFolyamErdemenyField = uieditfield(app.MaximlisfolyamTab, 'text');
            app.MaximalisFolyamErdemenyField.Editable = 'off';
            app.SajthlzatelemzsTab = uitab(app.TabGroup);
            app.SajthlzatelemzsTab.Title = 'Saját hálózat elemzés';
            app.SajthlzatelemzsTab.Units = 'pixels';
            app.BeolvasButton = uibutton(app.SajthlzatelemzsTab, 'push');
            app.BeolvasButton.ButtonPushedFcn = createCallbackFcn(app, @BeolvasButtonPushed, true);
            app.BeolvasButton.Text = 'Beolvas';
            app.lekszmaEditField_2Label = uilabel(app.SajthlzatelemzsTab);
            app.lekszmaEditField_2Label.Text = 'Élek száma:';
            app.lekszmaEditField_2 = uieditfield(app.SajthlzatelemzsTab, 'numeric');
            app.lekszmaEditField_2.Editable = 'off';
            app.CscsokszmaEditField_2Label = uilabel(app.SajthlzatelemzsTab);
            app.CscsokszmaEditField_2Label.Text = 'Csúcsok száma:';
            app.CscsokszmaEditField_2 = uieditfield(app.SajthlzatelemzsTab, 'numeric');
            app.CscsokszmaEditField_2.Editable = 'off';
            app.tlagosfokszmEditField_2Label = uilabel(app.SajthlzatelemzsTab);
            app.tlagosfokszmEditField_2Label.Text = 'Átlagos fokszám:';
            app.tlagosfokszmEditField_2 = uieditfield(app.SajthlzatelemzsTab, 'numeric');
            app.tlagosfokszmEditField_2.ValueDisplayFormat = '%5.2f';
            app.tlagosfokszmEditField_2.Editable = 'off';
            app.segedletKep = uiaxes(app.SajthlzatelemzsTab);
            app.ExcelmintaLabel = uilabel(app.SajthlzatelemzsTab);
            app.ExcelmintaLabel.Text = 'Excel minta:';
            app.SztancsikDnielSzegediTudomnyegyetem2017Label = uilabel(app.ComplexNetworkAnalysisUIFigure);
            app.SztancsikDnielSzegediTudomnyegyetem2017Label.Text = 'Sztancsik Dániel - Szegedi Tudományegyetem 2017';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ComplexNetworkAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ComplexNetworkAnalysisUIFigure)
        end
    end
end